classdef Team3_ProjectApp_V3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        WireGaugeDropDownLabel      matlab.ui.control.Label
        WireGaugeDropDown           matlab.ui.control.DropDown
        WireMaterialDropDownLabel   matlab.ui.control.Label
        WireMaterialDropDown        matlab.ui.control.DropDown
        CurrentAEditFieldLabel      matlab.ui.control.Label
        CurrentAEditField           matlab.ui.control.NumericEditField
        InsulationMaterialDropDownLabel  matlab.ui.control.Label
        InsulationMaterialDropDown  matlab.ui.control.DropDown
        CalculateButton             matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        CriticalRadiusmmLabel       matlab.ui.control.Label
        RcrLabel                    matlab.ui.control.Label
        tcrLabel                    matlab.ui.control.Label
        CriticalThicknessmmLabel    matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)

    end
    
    methods (Access = private)
        
        function calculate = calculate(app)
            %================== Obtain values from input areas
            k = str2num(app.InsulationMaterialDropDown.Value);
            r = str2num(app.WireGaugeDropDown.Value)/2;
            I = app.CurrentAEditField.Value;
            R = str2num(app.WireMaterialDropDown.Value);
            
            %================== Calculate the temperature difference
            %between the surface of the wire and the ambient temperature
            tempdiff = 0.0003226889*I^1.8348519061;
            
            %================== Calculate h and r_cr values
            h = k/((r/1000)*exp(2*pi^2*(r/1000)^2*k*tempdiff/I^2/R))
            r_cr = k/h*1000
            t_cr = r_cr-r;
            
            %================== Set axes and plot an image
            app.UIAxes.YLim = [floor(-r_cr)-1, ceil(r_cr)+1];
            app.UIAxes.XLim = [floor(-r_cr)-1, ceil(r_cr)+1];
            x = linspace(floor(-r_cr), ceil(r_cr), 100);
            plot(app.UIAxes, [x, x, x, x], [sqrt(r^2-x.^2), -sqrt(r^2-x.^2), sqrt(r_cr^2-x.^2), -sqrt(r_cr^2-x.^2)])
            app.RcrLabel.Text = append('Rcr = ', rptgen.toString(r_cr(1)));
            app.tcrLabel.Text = append('t cr = ', rptgen.toString(t_cr(1)));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            calculate(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WireGaugeDropDownLabel = uilabel(app.LeftPanel);
            app.WireGaugeDropDownLabel.Text = 'Wire Gauge';
            app.WireGaugeDropDown = uidropdown(app.LeftPanel);
            app.WireGaugeDropDown.Items = {'Select', '0000', '000', '00', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', ''};
            app.WireGaugeDropDown.ItemsData = {'0', '11.684', '10.40384', '9.26592', '8.25246', '7.34822', '6.54304', '5.82676', '5.18922', '4.62026', '4.1148', '3.66522', '3.2639', '2.90576', '2.58826', '2.30378', '2.05232', '1.8288', '1.62814', '1.45034', '1.29032', '1.15062', '1.02362', '0.91186', '0.8128'};
            app.WireGaugeDropDown.Value = '0';
            app.WireMaterialDropDownLabel = uilabel(app.LeftPanel);
            app.WireMaterialDropDownLabel.Text = 'Wire Material';
            app.WireMaterialDropDown = uidropdown(app.LeftPanel);
            app.WireMaterialDropDown.Items = {'Select', 'Copper', 'Aluminum'};
            app.WireMaterialDropDown.ItemsData = {'0', '1.68e-8', '2.735e-8', ''};
            app.WireMaterialDropDown.Value = '0';
            app.CurrentAEditFieldLabel = uilabel(app.LeftPanel);
            app.CurrentAEditFieldLabel.Text = 'Current (A)';
            app.CurrentAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InsulationMaterialDropDownLabel = uilabel(app.LeftPanel);
            app.InsulationMaterialDropDownLabel.Text = 'Insulation Material';
            app.InsulationMaterialDropDown = uidropdown(app.LeftPanel);
            app.InsulationMaterialDropDown.Items = {'Select', 'PVC', 'Nylon', 'Porcelain', 'Paper', 'Natural Rubber', 'Soft Vulcanized Rubber', 'Hard Vulcanized Rubber', 'Teflon', 'Human Skin'};
            app.InsulationMaterialDropDown.ItemsData = {'0', '0.1', '0.29', '1.5', '0.18', '.28', '.13', '.16', '0.35', '0.37'};
            app.InsulationMaterialDropDown.Value = '0';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RightPanel = uipanel(app.GridLayout);
            app.CriticalRadiusmmLabel = uilabel(app.RightPanel);
            app.CriticalRadiusmmLabel.Text = 'Critical Radius (mm):';
            app.RcrLabel = uilabel(app.RightPanel);
            app.RcrLabel.Text = {'Rcr ='; ''; ''};
            app.tcrLabel = uilabel(app.RightPanel);
            app.tcrLabel.Text = {'t cr ='; ''};
            app.CriticalThicknessmmLabel = uilabel(app.RightPanel);
            app.CriticalThicknessmmLabel.Text = 'Critical Thickness (mm):';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Image:')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Team3_ProjectApp_V3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end